classdef unterfacev7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        GoForItButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        HeartRateEditFieldLabel  matlab.ui.control.Label
        HeartRateEditField       matlab.ui.control.NumericEditField
        ReadmeEditFieldLabel     matlab.ui.control.Label
        ReadmeEditField          matlab.ui.control.EditField
        BloodSaO2EditFieldLabel  matlab.ui.control.Label
        BloodSaO2EditField       matlab.ui.control.NumericEditField
        FingerTypeDropDownLabel  matlab.ui.control.Label
        FingerTypeDropDown       matlab.ui.control.DropDown
        Gauge                    matlab.ui.control.Gauge
        LightLamp                matlab.ui.control.Lamp
        ThePulseOximeterCalculatorLabel  matlab.ui.control.Label
        CalibrateButton          matlab.ui.control.Button
        PortEditFieldLabel       matlab.ui.control.Label
        PortEditField            matlab.ui.control.EditField
        StopButton               matlab.ui.control.Button
    end

    
    properties (Access = public)    % Global Variables
        fingertype
        pwm1;
        pwm2;
        stop;
    end
    
    methods (Access = public)
        
        function results = plotGraph(app,x,y,lim1,lim2) % Plot Global Function
            plot(app.UIAxes,x,y,'r-');
            pause(0.001);
            app.UIAxes.YLim=[0 5];
            app.UIAxes.XLim=[lim1 lim2];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoForItButton
        function GoForItButtonPushed(app, event)
            
            % Close any serial print or plot before-hand
            try
                fclose(vSerial);
                delete
            catch
            end
            
            genpath('C:\Users\sruip\Desktop\PulseoxymeterRecording');
            
            %indicate the name of the serial port
            comPortName = app.PortEditField.Value; %Port name defined by the user
            
            %create a serial port
            vSerial = serial(comPortName);
            
            %configure the serial port
            set(vSerial,'BaudRate',19200);
            set(vSerial,'DataBits',8);
            set(vSerial,'Parity','none');
            set(vSerial,'StopBits',1);
            app.stop=0; %Initialized. It will be activated when button is pushed.
            
            %open the serial port
            fopen(vSerial);
            
            timing=4; % Time length of each of the light (red and infrared) in secs.
            
            %Initialization of limits
            lim1=0;
            lim2=2*timing;
            b=[];
            
            % Load audios
            load('audioPulsoAlto.mat')
            load('audioRecDedo.mat')
            load("audioPulsoBajo.mat")
            load("audioSatBaja.mat")
            
            % Initialize pulse and time vectors
            value=zeros(1,200001);
            xx=[];
            
            i=0;
            
            %Define axis in Appdesigner plot
            app.UIAxes.XLim=[lim1 lim2]; %Seconds
            app.UIAxes.YLim=[0 5]; %In Volts
            
            computed=0; %Delete vector b content.
            
            heart_rate=0; %Initialize heart rate
            
            % Initialize vectors' indixes
            positionb=0; % Position of vector b (b is used to plot and computations).
            positionx=1; % Position of time vector xx.
            s=0; %Iterations of 8 seconds
            error=0; % Initialization of error.
            
            % Initialize the calibration method
            load('ratiosFinal.mat') %Calibration document from artificial finger
            saturation=final(:,1);
            ratio=final(:,2);
            
            %%fitting for Saturation
            p=polyfit(saturation,ratio,1); %Polynomial fitting
            sat_final=0; %Initialization of final saturation
            satvalue=40:100; %Possible values of saturation
            
            for m=1:40
                ratio2(m)=polyval(p,39+m);
            end
            
            %%
            for m=41:60
                ratio2(m)=ratio(m-31);
            end
            
            % Initialize peak calculation variables
            thr=0.85; %Threshold over the max peak to be used.
            a=20; %time delay before light change
            
            if (app.fingertype==1) % Martin
                app.pwm1=12;
                app.pwm2=5;
                
            elseif (app.fingertype==2) % Samuel
                app.pwm1=13;
                app.pwm2=3;
                
            elseif (app.fingertype==3) % Jose
                app.pwm1=5;
                app.pwm2=3;
                
            elseif (app.fingertype==4) % Artificial finger
                app.pwm1=9;
                app.pwm2=2;
                
            else 
                app.pwm1=app.calibrated1
                
            end
            
            % Communicate with Arduino. Initialize.
            parameters=[1 1 app.pwm1 app.pwm2]; %wait, %routine %pwm1 %pwm2
            
            while(vSerial.BytesAvailable == 0) %Serial Port is opened.
                fwrite(vSerial,parameters,'uint8'); %Send these values
                pause(0.1);
            end
            
            %% Begin Loop
            while app.stop==0 %If button is not pressed.
                
                %Stablish which type of finger
                if computed==1 % At beginning and when SatO2 is computed
                    b=0; %Initialize/Clear plotting/calculation vector
                    positionb=1;
                end
                
                computed=0; %So that b is not cleared every loop.
                
                
                read_ard=fgets(vSerial);
                
                if(~isempty(read_ard) && read_ard(1)=='<')
                    new=split(read_ard,',');
                    try
                        i=i+1;
                        positionb=positionb+1;
                        value(i)=str2double(new{1,1}(2:end));
                        b(positionb)=value(i);
                        time=split(new{2,1},'>');
                        xx(i)=str2double(time{1,1})/1000; %Time value (millis) to time vector
                    catch
                    end
                end
                
                if isempty(read_ard) || app.stop==1
                    break
                end
                
                % Plot of the graph
                if rem(i,2)
                    plotGraph(app,xx(positionx:i),b(1:positionb)/1023*5,lim1,lim2); %global function
                end
                
                % Execute every 2*"timing" seconds, IR and red lights have flashed already
                % Every 2 Light Changes
                
                if i~=0 && b(positionb)==0 %Which is the value got from arduino, defining infrared light
                    
                    pos=0; % For ind_diff calculations 
                    done=0; %For Saturation Calculation
                    computed=1; %It will enter to clear b when loop repeated
                    
                    cla(app.UIAxes) % Clear the graph for computation speed purposes.
                    s=s+1; %Times it goes in this condition (8s)
                    
                    % Clear all variables
                    pulsex=[];
                    
                    % Red variables
                    minredp=[];
                    redp=[];
                    maxredp=[];
                    indexredp=[];
                    
                    % Infrared variables
                    maxIredp=[];
                    minIredp=[];
                    indexIredp=[];
                    Iredp=[];
                    
                    % Index light change
                    index1x=[];
                    
                    % Mean of the signals' peaks
                    redpav=[];
                    Iredpav=[];
                    
                    % Amplitude of the signals
                    amplred=[];
                    amplIred=[];
                    
                    % DC signals
                    DCred=[];
                    DCIred=[];
                    
                    % Saturations (log and not log)
                    o2sat1=[];
                    o2sat2=[];
                    dif=0;
                    aux=0;
                    
                    % Ind diff, heart_rate, sat_ final initialization
                    ind_diff1=[];
                    ind_diff2=[];
                    diff1=[];
                    diff2=[];
                    difftotal=0;
                    heart_rate=0;
                    sat_final=0;
                    
                    % We are supposing that there is max heart rate and
                    % the peaks cannot be closer than that rate.
                    
                    time_total=(xx(i)-xx(positionx));
                    sample_Avtime= length(b)/time_total; % Samples in a second
                    max_heart_rate=180/60; % Beats per second
                    time_per_beat=1/max_heart_rate; % Time per beat
                    
                    distance_peaks=floor(sample_Avtime*time_per_beat); % Index (unit is "samples")
                    
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    pulsex=b; % Auxiliar variable to compute
                    
                    % Prepare the variable containing the data
                    pulsex(1)=pulsex(2); %Eliminate 1st zero value
                    %                     pulsex(length(pulsex))=[]; %Eliminate last value
                    pulsex(length(pulsex))=[]; %Eliminate last zero
                    index1x=find(pulsex==1); %Find the index of Red Light Change
                    
                    % Find the peaks for red light (and relative min for amplitude)
                    maxredp=max(pulsex(a:(index1x))); % Max in first half region
                    
                    
                    try
                        [redp,indexredp]=findpeaks(pulsex(a:(index1x)),'MinPeakHeight',thr*maxredp,'MinPeakDistance',distance_peaks) %Threshold is applied, only maxima over it will be taken.
                        minredp=min(pulsex(indexredp(1):indexredp(length(indexredp)))); %minimum between the first and last peaks of red light
                        
                        % Find the peaks for Ired light
                        maxIredp=max(pulsex((index1x+a):(length(pulsex)))); % Max in second half region avoiding light change
                        [Iredp,indexIredp]=findpeaks(pulsex((index1x+a):(length(pulsex))),'MinPeakHeight',thr*maxIredp)
                        indexIredp=indexIredp+(index1x+a-1); % Scale the indexes of the Ired peak
                        minIredp=min(pulsex(indexIredp(1):indexIredp(length(indexIredp)))); %minimum between the first and last peaks of red light
                        
                        % Average red and Ired peaks
                        redpav=mean(redp);
                        Iredpav=mean(Iredp);
                        
                        % Find the amplitude of red and Ired lights
                        amplred=redpav-minredp;
                        amplIred=Iredpav-minIredp;
                        
                        % Find the relative DC component for both IR and
                        % red
                        DCred=mean(pulsex(a:(index1x))); %DC Component of red light
                        DCIred=mean(pulsex((index1x+a):(length(pulsex)))); %DC Component of Ired light
                        
                        %Calculation of Ox. Saturation
                        o2sat1=log10(amplIred/DCIred)/log10(amplred/DCred); % Alternative method for saturation calculation
                        o2sat2=(amplred/DCred)/(amplIred/DCIred)
                        
                        %%Calculate Saturation
                        dif=abs(ratio2(1)-o2sat2); %Difference of ration w\ respect to the calibration curve
                        
                        if s==1 %First iteration
                            sat_final=0;
                        else % Rest of Iterations
                            for k=2:length(ratio2)
                                aux=abs(ratio2(k)-o2sat2);
                                if(aux<dif) %Find Lowest difference (Best approach to the calibration curve)
                                    dif=aux;
                                    done=1; % Sat has 
                                    sat_final=satvalue(k);been calculated
                                end
                            end
                        end
                        
                        % Heart rate calculation.
                        % Ind_diff stands for the index difference among
                        % the peaks. As we have a standardized time vector,
                        % we can just use its time step to know the
                        % difference among peaks.
                        
                        if ~isempty(indexIredp) && length(indexIredp)>1
                            for j=2:length(indexIredp)
                                ind_diff1(j-1)=(xx(positionx+indexIredp(j))-xx(positionx+indexIredp(j-1))); % Time btw two peaks
                            end
                            diff1=mean(ind_diff1);
                        end
                                                
                        if ~isempty(indexredp) && length(indexredp)>1 
                            for l=2:length(indexredp)
                                ind_diff2(l-1)=(xx(positionx+indexredp(l))-xx(positionx+indexredp(l-1)));
                            end
                            diff2=mean(ind_diff2);
                        end                        
                                                                   
                        % Heart rate calculation
                        % Check possibilities: If error in one of both
                        % lights, calculate heart rate from the other.
                        if ~isempty(diff1) && ~isempty(diff2)
                            difftotal=mean([diff1,diff2]);
                            heart_rate=floor(60/(mean(difftotal)));
                        elseif ~isempty(diff1) && isempty(diff2)
                            heart_rate=floor(60/(mean(diff1)));
                        elseif isempty(diff1) && ~isempty(diff2)
                            heart_rate=floor(60/(mean(diff2)));
                        else % Both are empty
                            heart_rate=0;
                        end
                        
                        
                        % Tackling possible errors
                        if s~=1
                            if heart_rate<=50 && heart_rate>=40
                                app.ReadmeEditField.Value=('Your heart rate is pretty low!')
                                if error~=1 
                                    error=1;
                                    sound(audioPulsoBajo)
                                end
                                error=1;
                            elseif heart_rate<40
                                app.ReadmeEditField.Value=('Your heart rate is VERY LOW!!')
                                if error~=1
                                    error=1;
                                    sound(audioPulsoBajo)
                                end
                                error=1;
                            elseif heart_rate>=100 && heart_rate<=120
                                app.ReadmeEditField.Value=('Your heart rate is pretty high!')
                                if error~=2
                                    error=2;
                                    sound(audioPulsoAlto)
                                end
                                error=2;
                            elseif heart_rate>=120 && heart_rate<=180
                                app.ReadmeEditField.Value=('Your heart rate is VERY HIGH!!')
                                if error~=2
                                    error=2;
                                    sound(audioPulsoAlto)
                                end
                                error=2;
                            elseif heart_rate>180 || ~done || isempty(heart_rate)
                                app.ReadmeEditField.Value=('There was an error. Adequate your finger')
                                heart_rate=0;
                                sat_final=0;
                                if error~=4
                                    error=4;
                                    sound(audioRecDedo)
                                end
                                error=4;
                            elseif sat_final<94
                                app.ReadmeEditField.Value=('Your O2 saturation seems a little bit low!')
                                if error~=3
                                    error=3;
                                    sound(audioSatBaja)
                                end
                                error=3;
                            elseif sat_final<85
                                app.ReadmeEditField.Value=('Your O2 saturation is VERY LOW!!')
                                if error~=3
                                    error=3;
                                    sound(audioSatBaja)
                                end
                                error=3;
                            else
                                app.ReadmeEditField.Value=('')
                                error=0;
                            end
                            
                        else
                            app.ReadmeEditField.Value=('Muchas gracias por todo Lidia <3')
                            heart_rate=0;
                        end
                        
                    catch
                        app.ReadmeEditField.Value=('There was an error. Adequate your finger, please.')
                        o2sat2=0;
                        o2sat1=0;
                        sat_final=0;
                        heart_rate=0;
                        if s==1 
                            app.ReadmeEditField.Value=('Muchas gracias por todo Lidia <3')
                            heart_rate=0;
                        elseif error~=4
                            error=4;
                            sound(audioRecDedo)
                        end
                        error=4;
                        
                    end
                    lim1=xx(i);
                    lim2=lim1+8;
                    
                    
                    positionb=0;
                    positionx=i;
                    
                    %Display Heart Rate
                    try
                        app.HeartRateEditField.Value=heart_rate;
                    catch
                        app.HeartRateEditField.Value=0;
                    end
                    
                    %Gauge Display
                    try
                        app.Gauge.Value = heart_rate;
                    catch
                        app.Gauge.Value=0;
                    end
                    
                    %Final Saturation Display
                    sat_final
                    app.BloodSaO2EditField.Value=sat_final;
                    
                    %Light Display
                    app.LightLamp.Color = 'green';
                       
                end

                pause(0.001);
                
                if positionb~=0 
                    if ~isempty(b) && b(positionb)==1 
                    app.LightLamp.Color = 'red'; %Light Display
                    end
                end
                
            end
            
            fclose(vSerial);
            
        end

        % Value changed function: HeartRateEditField
        function HeartRateEditFieldValueChanged(app, event)
            value = app.HeartRateEditField.Value;
            
        end

        % Value changed function: FingerTypeDropDown
        function FingerTypeDropDownValueChanged(app, event)
            value = app.FingerTypeDropDown.Value;
            if strcmpi(value,'Martin')==1
                app.fingertype=1;
            elseif strcmpi(value,'Samu')==1
                app.fingertype=2;
            elseif strcmpi(value,'Jose')==1
                app.fingertype=3;
            elseif strcmpi(value,'Thin')==1
                app.fingertype=4;
            elseif strcmpi(value,'Medium')==1
                app.fingertype=5;
            elseif strcmpi(value,'Thick')==1
                app.fingertype=6;
            end 
        end

        % Value changed function: ReadmeEditField
        function ReadmeEditFieldValueChanged(app, event)
            value = app.ReadmeEditField.Value;       
        end

        % Value changed function: BloodSaO2EditField
        function BloodSaO2EditFieldValueChanged(app, event)
            value = app.BloodSaO2EditField.Value;    
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            try
                fclose(vSerial);
                delete
            catch
            end
            
            %indicate the name of the serial port
            comPortName = 'COM5';
            %create a serial port
            vSerial = serial(comPortName);
            %configure the serial port
            set(vSerial,'BaudRate',19200);
            set(vSerial,'DataBits',8);
            set(vSerial,'Parity','none');
            set(vSerial,'StopBits',1);
            
            lim1=0;
            lim2=4;
            
            %open the serial port
            fopen(vSerial);
            iter=1;
            i=0;
            value=zeros(1,20001);
            xx=[];
            value(1)=0;
            b=[];
            positionb=0;
            positionx=1;
            maxredp=[];
            minredp=[];
            
            parameters=[1 2 app.pwm1 app.pwm2]; %wait, %routine %pwm1 %pwm2
            
            while(vSerial.BytesAvailable == 0)
                fwrite(vSerial,parameters,'uint8');
                pause(0.1);
            end
            while i<10000
                
                a=fgets(vSerial);
                if(~isempty(a) && a(1)=='<')
                    new=split(a,',');
                    try
                        i=i+1;
                        positionb=positionb+1;
                        value(i)=str2double(new{1,1}(2:end));
                        b(positionb)=value(i);
                        time=split(new{2,1},'>');
                        xx(i)=str2double(time{1,1})/1000;
                        
                    catch
                    end
                    
                end
                
                
                if  i~=0 && xx(i)>=lim2
                    pulsex=b(1:positionb);
                    toc
                    done=0;
                    lim1=lim2;
                    lim2=lim2+4;
                    positionb=1;
                    
                    positionx=i;
                    
                    a=15; %time delay before light change
                    
                    %Find the peaks for red light (and relative min for amplitude)
                    maxredp(iter)=max(pulsex(a:end));
                    
                    pwmMax(iter)=iter; %minimum between the first and last peaks of red light
                    if (iter==15)
                        maximum=max(maxredp(2:end));
                        app.pwm1=pwmMax(find(maxredp==maximum,1))
                        break;
                    end
                    iter=iter+1;
                    
                end
                      if rem(i,2)
                    plotGraph(app,xx(positionx:i),b(1:positionb)/1023*5,lim1,lim2); %global function
                end
                
                
            end
            
            %             while(vSerial.BytesAvailable == 0)
            %                 fwrite(vSerial,3,'uint16');
            %
            %                 pause(0.1);
            %             end
            i=0;
            value=zeros(1,20001);
            xx=[];
            value(1)=0;
            b=[];
            iter=1;
            positionb=0;
            positionx=1;
            
            
            maxredp=[];
            minredp=[];
             parameters=[1 3 app.pwm1 app.pwm2]; %wait, %routine %pwm1 %pwm2
            
            while(vSerial.BytesAvailable == 0)
                fwrite(vSerial,parameters,'uint8');
                pause(0.1);
            end
            while i<10000
                tic
                
                
                a=fgets(vSerial);
                if(~isempty(a) && a(1)=='<')
                    new=split(a,',');
                    try
                        i=i+1;
                        positionb=positionb+1;
                        value(i)=str2double(new{1,1}(2:end));
                        b(positionb)=value(i);
                        time=split(new{2,1},'>');
                        xx(i)=str2double(time{1,1})/1000;
                        
                        
                    catch
                    end
                    
                    
                end
                
                
                if  i~=0 && xx(i)>=lim2
                    pulsex=b(1:positionb);
                    toc
                    done=0;
                    lim1=lim2;
                    lim2=lim2+4;
                    positionb=1;
                    
                    positionx=i;


                    a=15; %time delay before light change
                    
                    %Find the peaks for red light (and relative min for amplitude)
                    maxredp(iter)=max(pulsex(a:end));
                    pwmMax(iter)=iter; %minimum between the first and last peaks of red light
                    if (iter==5)
                        maximum=max(maxredp(2:end));
                        app.pwm2=pwmMax(find(maxredp==maximum,1))
                        break;
                    end
                    iter=iter+1;
                    
                    
                end
                if rem(i,2)
                    plotGraph(app,xx(positionx:i),b(1:positionb)/1023*5,lim1,lim2); %global function
                end


            end
            
            
            %close the port once you are done
            fclose(vSerial);
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'interface1.jpg';
            app.GoForItButton = uibutton(app.UIFigure, 'push');
            app.GoForItButton.ButtonPushedFcn = createCallbackFcn(app, @GoForItButtonPushed, true);
            app.GoForItButton.Text = 'GoForIt';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Red/Infrared Light')
            xlabel(app.UIAxes, 'Time (Seconds)')
            ylabel(app.UIAxes, 'Intensity (V)')
            app.HeartRateEditFieldLabel = uilabel(app.UIFigure);
            app.HeartRateEditFieldLabel.Text = 'Heart Rate';
            app.HeartRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @HeartRateEditFieldValueChanged, true);
            app.ReadmeEditFieldLabel = uilabel(app.UIFigure);
            app.ReadmeEditFieldLabel.Text = 'Read me!';
            app.ReadmeEditField = uieditfield(app.UIFigure, 'text');
            app.ReadmeEditField.ValueChangedFcn = createCallbackFcn(app, @ReadmeEditFieldValueChanged, true);
            app.BloodSaO2EditFieldLabel = uilabel(app.UIFigure);
            app.BloodSaO2EditFieldLabel.Text = 'Blood SaO2';
            app.BloodSaO2EditField = uieditfield(app.UIFigure, 'numeric');
            app.BloodSaO2EditField.ValueChangedFcn = createCallbackFcn(app, @BloodSaO2EditFieldValueChanged, true);
            app.FingerTypeDropDownLabel = uilabel(app.UIFigure);
            app.FingerTypeDropDownLabel.Text = 'Finger Type';
            app.FingerTypeDropDown = uidropdown(app.UIFigure);
            app.FingerTypeDropDown.Items = {'Martin', 'Samu', 'Jose', 'Thin', 'Medium', 'Thick'};
            app.FingerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FingerTypeDropDownValueChanged, true);
            app.FingerTypeDropDown.Value = 'Martin';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [40 160];
            app.LightLamp = uilamp(app.UIFigure);
            app.ThePulseOximeterCalculatorLabel = uilabel(app.UIFigure);
            app.ThePulseOximeterCalculatorLabel.Text = 'The Pulse Oximeter Calculator ';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 'COM5';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unterfacev7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end